classdef CompareImages < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        LeftPanel            matlab.ui.container.Panel
        UIAxesOriginal       matlab.ui.control.UIAxes
        CenterPanel          matlab.ui.container.Panel
        FrameSliderLabel     matlab.ui.control.Label
        FrameSlider          matlab.ui.control.Slider
        ChooseDayPathButton  matlab.ui.control.Button
        LoadButton           matlab.ui.control.Button
        ActiveDayPathLabel   matlab.ui.control.Label
        txtActiveDayPath     matlab.ui.control.TextArea
        RegisteredPathLabel  matlab.ui.control.Label
        lblRegisteredPath    matlab.ui.control.Label
        CorrectedPathLabel   matlab.ui.control.Label
        lblCorrectedPath     matlab.ui.control.Label
        FileTree             matlab.ui.container.Tree
        CorrectedFilesNode   matlab.ui.container.TreeNode
        Node2                matlab.ui.container.TreeNode
        Node3                matlab.ui.container.TreeNode
        Node4                matlab.ui.container.TreeNode
        btnStatusInfo        matlab.ui.control.Button
        UIAxesCompared       matlab.ui.control.UIAxes
        RightPanel           matlab.ui.container.Panel
        UIAxesCorrected      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = public)
        activeDirectory = 'Z:\ICPassiveStim\anm265\20200120_PassiveStim_Registered\FoV1Lateral\suite2p\plane0' % Description
        activeOriginalTifDirectory % Description
        activeCorrectedTifDirectory % The corrected tif dir
        activeOriginalTifFilesDatastore % Description
        activeCorrectedTifFilesDatastore % Description
        activeOriginalLoadedTifFilePath = '' % The file path for the currently loaded file
        activeOriginalLoadedTifStack % A stack of tif frame data for the currently loaded file
        activeCorrectedLoadedTifFilePath = '' % The file path for the currently loaded file
        activeCorrectedLoadedTifStack % A stack of tif frame data for the currently loaded file
    end
    
    properties (Access = private)
        oldPointer % A cached version of the previous pointer to be restored when busy
    end
    
    methods (Access = public)
        
        function performSelectDirectory(app)
            app.txtActiveDayPath.Value = app.activeDirectory;
            app.activeOriginalTifDirectory = fullfile(app.activeDirectory, 'reg_tif');
            app.activeCorrectedTifDirectory = fullfile(app.activeDirectory, 'reg_tif_aligned');
            app.lblRegisteredPath.Text = app.activeOriginalTifDirectory;
            app.lblCorrectedPath.Text = app.activeCorrectedTifDirectory;
            app.openImageDatastore(); % Auto open the datastore
        end
        
        function openImageDatastore(app)
        
            % Disable the slider:
            app.indicateBusy();
            
            
            has_valid_original_tif_dir = exist(app.activeOriginalTifDirectory, 'dir');
            has_valid_corrected_tif_dir = exist(app.activeCorrectedTifDirectory, 'dir');
            
            
            if (~has_valid_original_tif_dir || ~has_valid_corrected_tif_dir)
                warning('The path does not exist!');
                app.ChooseDayPathButtonPushed([]); % Make the user choose a new path                
            end
            
            % Load the datasources
            app.activeOriginalTifFilesDatastore = imageDatastore(app.activeOriginalTifDirectory,'IncludeSubfolders',false,'FileExtensions','.tif','LabelSource','foldernames','ReadFcn', @fnCustomTifStackReader);
            app.activeCorrectedTifFilesDatastore = imageDatastore(app.activeCorrectedTifDirectory,'IncludeSubfolders',false,'FileExtensions','.tif','LabelSource','foldernames','ReadFcn', @fnCustomTifStackReader);
            
            % Extract information from the loaded datastores
            filesCount = size(app.activeCorrectedTifFilesDatastore.Files, 1);
            
            % Update the tree
            oldNodes = app.CorrectedFilesNode.Children;
            oldNodes.delete;
            
            for i = 1:filesCount
                curr_node_data.curr_filepath = app.activeCorrectedTifFilesDatastore.Files{i};
                [curr_node_data.curr_parentPath, curr_node_data.curr_basename, curr_node_data.curr_ext] = fileparts(curr_node_data.curr_filepath);
                curr_node_data.curr_filename = [curr_node_data.curr_basename curr_node_data.curr_ext];
                curr_node_data.file_index = i;
                
                p1 = uitreenode(app.CorrectedFilesNode,'Text',curr_node_data.curr_basename,'NodeData',[curr_node_data]);
            end
            % Expand the tree
            expand(app.FileTree);

            app.indicateFinished();
            
            
        end
        
        function loadFilesWithName(app, filename)
            % Given a filename, loads the files from each the original
            % registered and corrected folders:
            app.indicateBusy();
            
            app.activeOriginalLoadedTifFilePath = fullfile(app.activeOriginalTifDirectory, filename);
            app.activeCorrectedLoadedTifFilePath = fullfile(app.activeCorrectedTifDirectory, filename);
    
            fprintf('Loading: \n \t %s\n \t %s\n', app.activeOriginalLoadedTifFilePath, app.activeCorrectedLoadedTifFilePath);
%             currLoadedData = bfOpen3DVolume(curr_original_tif_filePath);
            fprintf('\t \t Loading %s...', app.activeOriginalLoadedTifFilePath);
            app.activeOriginalLoadedTifStack = loadtiff(app.activeOriginalLoadedTifFilePath);
            fprintf('done.\n');
            
            fprintf('\t \t Loading %s...\n', app.activeCorrectedLoadedTifFilePath);
            app.activeCorrectedLoadedTifStack = loadtiff(app.activeCorrectedLoadedTifFilePath);
            fprintf('done.\n');
            
            frameCount = size(app.activeCorrectedLoadedTifStack, 3);
            
            % Slider is for frames.
            app.FrameSlider.Limits = [1 frameCount];
            app.FrameSlider.Value = 1;
            
            app.indicateFinished();
            app.FrameSlider.Enable = true;   
         end
        
        function changeLoadedFrame(app, curr_frame_index)
            fprintf('Selecting frame %d\n', curr_frame_index);
            app.indicateBusy();
            
            curr_original_frame = app.activeOriginalLoadedTifStack(:,:,curr_frame_index);
            curr_corrected_frame = app.activeCorrectedLoadedTifStack(:,:,curr_frame_index);
            % Display image and stretch to fill axes
            I_original = imshow(curr_original_frame, 'Parent', app.UIAxesOriginal);
            app.UIAxesOriginal.Title.String = ['Original Frame[' num2str(curr_frame_index) ']'];

            I_corrected = imshow(curr_corrected_frame, 'Parent', app.UIAxesCorrected);
            app.UIAxesCorrected.Title.String = ['Corrected Frame[' num2str(curr_frame_index) ']'];

            imshowpair(curr_original_frame, curr_corrected_frame, 'Parent', app.UIAxesCompared);
            app.UIAxesCompared.Title.String = ['Frame[' num2str(curr_frame_index) '] Comparison'];

            app.indicateFinished();
            app.FrameSlider.Enable = true;
        end
    end
    
    methods (Access = private)


        function indicateBusy(app)
            app.FrameSlider.Enable = false;
            app.FileTree.Enable = false;
            app.txtActiveDayPath.Enable = false;
            app.LoadButton.Enable = false;
            app.ChooseDayPathButton.Enable = false;
%             app.oldpointer = get(app, 'pointer');
%             set(app, 'pointer', 'watch')

%             app.btnStatusInfo.Text = 'Loading... Please Wait. This might take a minute or two.';
            app.btnStatusInfo.Visible = true;
            drawnow;
            % your computation goes here
            
        end
        
        function indicateFinished(app)
%             set(app, 'pointer', app.oldpointer)
            app.btnStatusInfo.Visible = false;
            
            app.FileTree.Enable = true;
            app.txtActiveDayPath.Enable = true;
            app.LoadButton.Enable = true;
            app.ChooseDayPathButton.Enable = true;
            drawnow;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.performSelectDirectory(); % Load the default path
        end

        % Button pushed function: ChooseDayPathButton
        function ChooseDayPathButtonPushed(app, event)
            fileParentPath = uigetdir(pwd, 'Select a day folder');
            drawnow;
            %figure(app.PhoVideoBackgroundExtractorUIFigure)
            
            user_canceled = (fileParentPath == 0);
            if ~user_canceled
                
                fprintf('User chose path: %s\n', fileParentPath)
                %app.onOpenSourceVideo(fileParentPath, fileName)
                app.activeDirectory = fileParentPath;
                app.performSelectDirectory();
                
            else
                fprintf('User canceled\n')
                %app.actionEndLoading();
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            app.openImageDatastore();
        end

        % Value changed function: FrameSlider
        function FrameSliderValueChanged(app, event)
            curr_frame_index = round(app.FrameSlider.Value);
            app.changeLoadedFrame(curr_frame_index);
        end

        % Selection changed function: FileTree
        function FileTreeSelectionChanged(app, event)
            selectedNodes = app.FileTree.SelectedNodes;
            selectedFilename = selectedNodes.NodeData.curr_filename;
            app.loadFilesWithName(selectedFilename);   
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {570, 570, 570};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {570, 570};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {245, '1x', 233};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Pho Compare Frames';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UIAxesOriginal = uiaxes(app.LeftPanel);
            title(app.UIAxesOriginal, 'Original Frame')
            app.UIAxesOriginal.XTick = [];
            app.UIAxesOriginal.YTick = [];
            app.CenterPanel = uipanel(app.GridLayout);
            app.FrameSliderLabel = uilabel(app.CenterPanel);
            app.FrameSliderLabel.Enable = 'off';
            app.FrameSliderLabel.Text = 'Frame Slider';
            app.FrameSlider = uislider(app.CenterPanel);
            app.FrameSlider.Limits = [1 100];
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @FrameSliderValueChanged, true);
            app.FrameSlider.Enable = 'off';
            app.FrameSlider.Value = 1;
            app.ChooseDayPathButton = uibutton(app.CenterPanel, 'push');
            app.ChooseDayPathButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseDayPathButtonPushed, true);
            app.ChooseDayPathButton.Text = 'Choose...';
            app.LoadButton = uibutton(app.CenterPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.ActiveDayPathLabel = uilabel(app.CenterPanel);
            app.ActiveDayPathLabel.Text = 'Active Day Path:';
            app.txtActiveDayPath = uitextarea(app.CenterPanel);
            app.RegisteredPathLabel = uilabel(app.CenterPanel);
            app.RegisteredPathLabel.Text = 'Registered Path:';
            app.lblRegisteredPath = uilabel(app.CenterPanel);
            app.lblRegisteredPath.Enable = 'off';
            app.lblRegisteredPath.Text = 'None';
            app.CorrectedPathLabel = uilabel(app.CenterPanel);
            app.CorrectedPathLabel.Text = 'Corrected Path:';
            app.lblCorrectedPath = uilabel(app.CenterPanel);
            app.lblCorrectedPath.Enable = 'off';
            app.lblCorrectedPath.Text = 'None';
            app.FileTree = uitree(app.CenterPanel);
            app.FileTree.SelectionChangedFcn = createCallbackFcn(app, @FileTreeSelectionChanged, true);
            app.CorrectedFilesNode = uitreenode(app.FileTree);
            app.CorrectedFilesNode.Text = 'Corrected Files';
            app.Node2 = uitreenode(app.CorrectedFilesNode);
            app.Node2.Text = 'Node2';
            app.Node3 = uitreenode(app.CorrectedFilesNode);
            app.Node3.Text = 'Node3';
            app.Node4 = uitreenode(app.CorrectedFilesNode);
            app.Node4.Text = 'Node4';
            app.btnStatusInfo = uibutton(app.CenterPanel, 'push');
            app.btnStatusInfo.Visible = 'off';
            app.btnStatusInfo.Text = 'Loading... Please Wait. This might take a minute or two.';
            app.UIAxesCompared = uiaxes(app.CenterPanel);
            title(app.UIAxesCompared, 'Frame Comparison')
            app.UIAxesCompared.XTick = [];
            app.UIAxesCompared.YTick = [];
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxesCorrected = uiaxes(app.RightPanel);
            title(app.UIAxesCorrected, 'CorrectedFrame')
            app.UIAxesCorrected.XTick = [];
            app.UIAxesCorrected.YTick = [];
            app.UIAxesCorrected.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CompareImages

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end