classdef MAIN_InteractivePipelineApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Menu                           matlab.ui.container.Menu
        Menu2                          matlab.ui.container.Menu
        GridLayout3                    matlab.ui.container.GridLayout
        ApostolidesLab2020Label        matlab.ui.control.Label
        Pho2PhotonPipelineLabel        matlab.ui.control.Label
        ExitButton                     matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        MainPipelineTab                matlab.ui.container.Tab
        MainPipelinePanel              matlab.ui.container.Panel
        GridLayout_3                   matlab.ui.container.GridLayout
        RebuildFinalDataStructureFDSButton  matlab.ui.control.Button
        Label_5                        matlab.ui.control.Label
        ExecutePostFDSLoadingAnalysisButton  matlab.ui.control.Button
        Label_6                        matlab.ui.control.Label
        BuildSpatialTuningInfoButton   matlab.ui.control.Button
        Label_7                        matlab.ui.control.Label
        ConfigurePipelineSettingsButton  matlab.ui.control.Button
        MultiDayAlignmentPipelineTab   matlab.ui.container.Tab
        MultiDayAlignmentRegistrationPipelinePanel  matlab.ui.container.Panel
        GridLayout_2                   matlab.ui.container.GridLayout
        ComputeCrossDayAlignmentsButton  matlab.ui.control.Button
        Label_3                        matlab.ui.control.Label
        ApplyComputedAlignmentsButton  matlab.ui.control.Button
        Label_4                        matlab.ui.control.Label
        UtilitiesTab                   matlab.ui.container.Tab
        GridLayout2                    matlab.ui.container.GridLayout
        PostSuite2pPanel               matlab.ui.container.Panel
        GridLayout                     matlab.ui.container.GridLayout
        LaunchSessionSplittingButton   matlab.ui.control.Button
        UsedtocreatesplitsessionsfromthefAllmatfileoutputbySuite2pLabel  matlab.ui.control.Label
        CompareImagesButton            matlab.ui.control.Button
        Label                          matlab.ui.control.Label
        VisualizationTab               matlab.ui.container.Tab
        GridLayout5                    matlab.ui.container.GridLayout
        TuningPanel                    matlab.ui.container.Panel
        GridLayout4_2                  matlab.ui.container.GridLayout
        LinkedInteractiveSliderButton  matlab.ui.control.StateButton
        PlotAllandSaveToDiskButton     matlab.ui.control.Button
        ROIsPanel                      matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        SpatialMaskAnalysisButton      matlab.ui.control.StateButton
        RemoteLamp                     matlab.ui.control.Lamp
        RemoteLampLabel                matlab.ui.control.Label
        Button                         matlab.ui.control.StateButton
        CurrIndexSpinnerLabel          matlab.ui.control.Label
        CurrIndexSpinner               matlab.ui.control.Spinner
    end

    
    properties (Access = private)
        PreferencesSubApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath('helpers'));
            addpath(genpath('Interactive'));
            addpath(genpath('data'));
            addpath(genpath('Pre-Suite2p Alignment'));
            addpath(genpath('Post Suite2p Processing'));
        end

        % Button pushed function: LaunchSessionSplittingButton
        function LaunchSessionSplittingButtonPushed(app, event)
            PhoPostSuite2pSessionSplitting;
        end

        % Button pushed function: ComputeCrossDayAlignmentsButton
        function LaunchCrossDayAlignButtonPushed(app, event)
            PhoRigidAlignmentCompute;
            
        end

        % Button pushed function: ApplyComputedAlignmentsButton
        function ApplyComputedAlignmentsButtonPushed(app, event)
            PhoApplyAlignmentCorrections
        end

        % Button pushed function: CompareImagesButton
        function CompareImagesButtonPushed(app, event)
            CompareImages
        end

        % Button pushed function: ConfigurePipelineSettingsButton
        function ConfigurePipelineSettingsButtonPushed(app, event)
            if ~exist('phoPipelineOptions','var')
                PhoPipelineOptions; % Run the startup script
            end
            [PreferencesSubApp.hPropsPane, phoPipelineOptions] = propertiesGUI(app, phoPipelineOptions);
        end

        % Button pushed function: RebuildFinalDataStructureFDSButton
        function RebuildFinalDataStructureFDSButtonPushed(app, event)
            PhoLoadFinalDataStruct;
        end

        % Button pushed function: 
        % ExecutePostFDSLoadingAnalysisButton
        function ExecutePostFDSLoadingAnalysisButtonPushed(app, event)
            PhoPostFinalDataStructAnalysis;
        end

        % Button pushed function: BuildSpatialTuningInfoButton
        function BuildSpatialTuningInfoButtonPushed(app, event)
            PhoBuildSpatialTuning;
        end

        % Value changed function: SpatialMaskAnalysisButton
        function SpatialMaskAnalysisButtonValueChanged(app, event)
            value = app.SpatialMaskAnalysisButton.Value;
            PhoFastPlottingTesting;
            
        end

        % Value changed function: LinkedInteractiveSliderButton
        function LinkedInteractiveSliderButtonValueChanged(app, event)
            value = app.LinkedInteractiveSliderButton.Value;
            OLD_ShowSliderControlledLinkedInteractiveFigures;
            evalin("base")
        end

        % Button pushed function: PlotAllandSaveToDiskButton
        function PlotAllandSaveToDiskButtonPushed(app, event)
            plotAllAndSaveToDisk;
        end

        % Value changed function: Button
        function ButtonValueChanged(app, event)
            value = app.Button.Value;
            
            
        end

        % Value changed function: CurrIndexSpinner
        function CurrIndexSpinnerValueChanged(app, event)
            value = app.CurrIndexSpinner.Value;
            %% TODO: Update the displayed CellROI
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            %% TODO: Quits the app
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.UIFigure);
            app.Menu2.Text = 'Menu2';
            app.GridLayout3 = uigridlayout(app.UIFigure);
            app.ApostolidesLab2020Label = uilabel(app.GridLayout3);
            app.ApostolidesLab2020Label.Text = 'Apostolides Lab 2020';
            app.Pho2PhotonPipelineLabel = uilabel(app.GridLayout3);
            app.Pho2PhotonPipelineLabel.Text = 'Pho 2Photon Pipeline';
            app.ExitButton = uibutton(app.GridLayout3, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.TabGroup = uitabgroup(app.GridLayout3);
            app.MainPipelineTab = uitab(app.TabGroup);
            app.MainPipelineTab.Title = 'Main Pipeline';
            app.MainPipelinePanel = uipanel(app.MainPipelineTab);
            app.MainPipelinePanel.Title = 'Main Pipeline';
            app.GridLayout_3 = uigridlayout(app.MainPipelinePanel);
            app.RebuildFinalDataStructureFDSButton = uibutton(app.GridLayout_3, 'push');
            app.RebuildFinalDataStructureFDSButton.ButtonPushedFcn = createCallbackFcn(app, @RebuildFinalDataStructureFDSButtonPushed, true);
            app.RebuildFinalDataStructureFDSButton.Text = 'Rebuild Final Data Structure (FDS)';
            app.Label_5 = uilabel(app.GridLayout_3);
            app.Label_5.Text = 'If the PhoPostSuite2pSessionSplitting script has already been ran and produced a combined data struct, you can load this from disk.';
            app.ExecutePostFDSLoadingAnalysisButton = uibutton(app.GridLayout_3, 'push');
            app.ExecutePostFDSLoadingAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @ExecutePostFDSLoadingAnalysisButtonPushed, true);
            app.ExecutePostFDSLoadingAnalysisButton.Text = 'Execute Post FDS Loading Analysis';
            app.Label_6 = uilabel(app.GridLayout_3);
            app.Label_6.Text = 'Uses the finalDataStruct workspace variable and shows results';
            app.BuildSpatialTuningInfoButton = uibutton(app.GridLayout_3, 'push');
            app.BuildSpatialTuningInfoButton.ButtonPushedFcn = createCallbackFcn(app, @BuildSpatialTuningInfoButtonPushed, true);
            app.BuildSpatialTuningInfoButton.Text = 'Build Spatial Tuning Info';
            app.Label_7 = uilabel(app.GridLayout_3);
            app.Label_7.Text = {'Builds relations been each cells spatial location and their tuning.'; ''};
            app.ConfigurePipelineSettingsButton = uibutton(app.MainPipelineTab, 'push');
            app.ConfigurePipelineSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigurePipelineSettingsButtonPushed, true);
            app.ConfigurePipelineSettingsButton.Text = 'Configure Pipeline Settings';
            app.MultiDayAlignmentPipelineTab = uitab(app.TabGroup);
            app.MultiDayAlignmentPipelineTab.Title = 'Multi-Day Alignment Pipeline';
            app.MultiDayAlignmentRegistrationPipelinePanel = uipanel(app.MultiDayAlignmentPipelineTab);
            app.MultiDayAlignmentRegistrationPipelinePanel.Title = 'Multi-Day Alignment/Registration Pipeline';
            app.GridLayout_2 = uigridlayout(app.MultiDayAlignmentRegistrationPipelinePanel);
            app.ComputeCrossDayAlignmentsButton = uibutton(app.GridLayout_2, 'push');
            app.ComputeCrossDayAlignmentsButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchCrossDayAlignButtonPushed, true);
            app.ComputeCrossDayAlignmentsButton.Text = 'Compute Cross-Day Alignments';
            app.Label_3 = uilabel(app.GridLayout_2);
            app.Label_3.Text = 'Computes the translations required to align all registered .tif files in a date folder with the "reference" date folder (the first one in the cell array).';
            app.ApplyComputedAlignmentsButton = uibutton(app.GridLayout_2, 'push');
            app.ApplyComputedAlignmentsButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyComputedAlignmentsButtonPushed, true);
            app.ApplyComputedAlignmentsButton.Text = 'Apply Computed Alignments';
            app.Label_4 = uilabel(app.GridLayout_2);
            app.Label_4.Text = 'Takes alignment offsets computed by the previous stage in the pipeline and applies them to the registered .tifs, saving them out to a new ''reg_tif_aligned'' directory adjacent to the ''reg_tif'' directory.';
            app.UtilitiesTab = uitab(app.TabGroup);
            app.UtilitiesTab.Title = 'Utilities';
            app.GridLayout2 = uigridlayout(app.UtilitiesTab);
            app.PostSuite2pPanel = uipanel(app.GridLayout2);
            app.PostSuite2pPanel.Title = 'Post Suite2p';
            app.GridLayout = uigridlayout(app.PostSuite2pPanel);
            app.LaunchSessionSplittingButton = uibutton(app.GridLayout, 'push');
            app.LaunchSessionSplittingButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchSessionSplittingButtonPushed, true);
            app.LaunchSessionSplittingButton.Text = 'Launch Session Splitting';
            app.UsedtocreatesplitsessionsfromthefAllmatfileoutputbySuite2pLabel = uilabel(app.GridLayout);
            app.UsedtocreatesplitsessionsfromthefAllmatfileoutputbySuite2pLabel.Text = 'Used to create split sessions from the fAll.mat file output by Suite2p';
            app.CompareImagesButton = uibutton(app.GridLayout, 'push');
            app.CompareImagesButton.ButtonPushedFcn = createCallbackFcn(app, @CompareImagesButtonPushed, true);
            app.CompareImagesButton.Text = 'Compare Images';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = 'Allows comparing registered .tif files in a date folder with the "reference" date folder (the first one in the cell array).';
            app.VisualizationTab = uitab(app.TabGroup);
            app.VisualizationTab.Title = 'Visualization';
            app.GridLayout5 = uigridlayout(app.VisualizationTab);
            app.TuningPanel = uipanel(app.GridLayout5);
            app.TuningPanel.Title = 'Tuning';
            app.GridLayout4_2 = uigridlayout(app.TuningPanel);
            app.LinkedInteractiveSliderButton = uibutton(app.GridLayout4_2, 'state');
            app.LinkedInteractiveSliderButton.ValueChangedFcn = createCallbackFcn(app, @LinkedInteractiveSliderButtonValueChanged, true);
            app.LinkedInteractiveSliderButton.Text = 'Linked Interactive Slider';
            app.PlotAllandSaveToDiskButton = uibutton(app.GridLayout4_2, 'push');
            app.PlotAllandSaveToDiskButton.ButtonPushedFcn = createCallbackFcn(app, @PlotAllandSaveToDiskButtonPushed, true);
            app.PlotAllandSaveToDiskButton.Text = 'Plot All and Save To Disk';
            app.ROIsPanel = uipanel(app.GridLayout5);
            app.ROIsPanel.Title = 'ROIs';
            app.GridLayout4 = uigridlayout(app.ROIsPanel);
            app.SpatialMaskAnalysisButton = uibutton(app.GridLayout4, 'state');
            app.SpatialMaskAnalysisButton.ValueChangedFcn = createCallbackFcn(app, @SpatialMaskAnalysisButtonValueChanged, true);
            app.SpatialMaskAnalysisButton.Text = 'Spatial Mask Analysis';
            app.RemoteLamp = uilamp(app.GridLayout3);
            app.RemoteLampLabel = uilabel(app.GridLayout3);
            app.RemoteLampLabel.Text = 'Remote';
            app.Button = uibutton(app.GridLayout3, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Text = 'Button';
            app.CurrIndexSpinnerLabel = uilabel(app.GridLayout3);
            app.CurrIndexSpinnerLabel.Text = 'CurrIndex';
            app.CurrIndexSpinner = uispinner(app.GridLayout3);
            app.CurrIndexSpinner.Limits = [1 Inf];
            app.CurrIndexSpinner.RoundFractionalValues = 'on';
            app.CurrIndexSpinner.ValueDisplayFormat = '%.0f';
            app.CurrIndexSpinner.ValueChangedFcn = createCallbackFcn(app, @CurrIndexSpinnerValueChanged, true);
            app.CurrIndexSpinner.Tag = 'spinCurrIndexLabel';
            app.CurrIndexSpinner.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MAIN_InteractivePipelineApp

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end